classdef EEGmotifs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGmotifsUIFigure             matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        SelectLoadingButton           matlab.ui.control.Button
        SelectSavingButton            matlab.ui.control.Button
        SelectsourcefolderLabel       matlab.ui.control.Label
        SelectdestinationfolderLabel  matlab.ui.control.Label
        SelectnumberofmotifsButtonGroup  matlab.ui.container.ButtonGroup
        Button_motif3                 matlab.ui.control.RadioButton
        Button_motif4                 matlab.ui.control.RadioButton
        ReadyLampLabel                matlab.ui.control.Label
        ReadyLamp                     matlab.ui.control.Lamp
        FindMotifsButton              matlab.ui.control.Button
        MatrixTypeButtonGroup         matlab.ui.container.ButtonGroup
        dPLIButton                    matlab.ui.control.RadioButton
        NSTEButton                    matlab.ui.control.RadioButton
        Thresholding01EditFieldLabel  matlab.ui.control.Label
        Thresholding01EditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        loading_path = "";
        saving_path = "";
        motif_number = 0;
        isLoading = 0;
        isSaving = 0;
    end

    methods (Access = private)
    
        function [is_ready] = isReady(app)
            if(app.isSaving && app.isLoading)
               app.ReadyLamp.Color =  [0,1,0];
               is_ready = 1;
            else
                app.ReadyLamp.Color = [1,0,0];
                is_ready = 0;
            end
        end
        
    end


    methods (Access = private)

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            app.delete();
        end

        % Button pushed function: SelectLoadingButton
        function SelectLoadingButtonPushed(app, event)
            selected_path = uigetdir;
            app.EEGmotifsUIFigure.Visible = 'off';
            app.EEGmotifsUIFigure.Visible = 'on';            
            if isequal(selected_path,0)
                disp('User selected Cancel');
                app.isLoading = 0;
                app.loading_path ="";
                return;
            else
               app.loading_path = selected_path;
               app.isLoading =  1;
               isReady(app);
            end
        end

        % Button pushed function: SelectSavingButton
        function SelectSavingButtonPushed(app, event)
            selected_path = uigetdir;
            app.EEGmotifsUIFigure.Visible = 'off';
            app.EEGmotifsUIFigure.Visible = 'on';
            if isequal(selected_path,0)
                disp('User selected Cancel');
                app.isSaving = 0;
                app.saving_path ="";
                return;
            else
               app.saving_path = selected_path;
               app.isSaving = 1;
               isReady(app);
            end
        end

        % Button pushed function: FindMotifsButton
        function FindMotifsButtonPushed(app, event)
            if(is_ready(app))
                
                if(app.Button_motif3.Value == 1)
                    app.motif_number = 3;
                else
                    app.motif_number = 4;
                end
                
                if(app.dPLIButton.Value == 1)
                    type = 'dpli';
                else
                    type = 'nste';
                end
                
                threshold = app.Thresholding01EditField.Value;
                calculate_all_motifs(app.loading_path,type,threshold,app.motif_number,app.saving_path);
                app.isSaving = 0;
                app.isLoading = 0;
                isReady(app);
                disp('Motif analysis terminated');
            else
                disp('Please select a saving and a loading directory');
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGmotifsUIFigure = uifigure;
            app.EEGmotifsUIFigure.Name = 'EEGmotifs';
            app.FileMenu = uimenu(app.EEGmotifsUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.SelectLoadingButton = uibutton(app.EEGmotifsUIFigure, 'push');
            app.SelectLoadingButton.ButtonPushedFcn = createCallbackFcn(app, @SelectLoadingButtonPushed, true);
            app.SelectLoadingButton.Text = 'Select';
            app.SelectSavingButton = uibutton(app.EEGmotifsUIFigure, 'push');
            app.SelectSavingButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSavingButtonPushed, true);
            app.SelectSavingButton.Text = 'Select';
            app.SelectsourcefolderLabel = uilabel(app.EEGmotifsUIFigure);
            app.SelectsourcefolderLabel.Text = 'Select source folder';
            app.SelectdestinationfolderLabel = uilabel(app.EEGmotifsUIFigure);
            app.SelectdestinationfolderLabel.Text = 'Select destination folder';
            app.SelectnumberofmotifsButtonGroup = uibuttongroup(app.EEGmotifsUIFigure);
            app.SelectnumberofmotifsButtonGroup.Title = 'Select number of motifs';
            app.Button_motif3 = uiradiobutton(app.SelectnumberofmotifsButtonGroup);
            app.Button_motif3.Text = '3';
            app.Button_motif3.Value = true;
            app.Button_motif4 = uiradiobutton(app.SelectnumberofmotifsButtonGroup);
            app.Button_motif4.Text = '4';
            app.ReadyLampLabel = uilabel(app.EEGmotifsUIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.EEGmotifsUIFigure);
            app.FindMotifsButton = uibutton(app.EEGmotifsUIFigure, 'push');
            app.FindMotifsButton.ButtonPushedFcn = createCallbackFcn(app, @FindMotifsButtonPushed, true);
            app.FindMotifsButton.Text = 'Find Motifs';
            app.MatrixTypeButtonGroup = uibuttongroup(app.EEGmotifsUIFigure);
            app.MatrixTypeButtonGroup.Title = 'Matrix Type';
            app.dPLIButton = uiradiobutton(app.MatrixTypeButtonGroup);
            app.dPLIButton.Text = 'dPLI';
            app.dPLIButton.Value = true;
            app.NSTEButton = uiradiobutton(app.MatrixTypeButtonGroup);
            app.NSTEButton.Text = 'NSTE';
            app.Thresholding01EditFieldLabel = uilabel(app.EEGmotifsUIFigure);
            app.Thresholding01EditFieldLabel.Text = 'Thresholding (0 - 1)';
            app.Thresholding01EditField = uieditfield(app.EEGmotifsUIFigure, 'numeric');
            app.Thresholding01EditField.Limits = [0 1];
            app.Thresholding01EditField.Value = 0.1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEGmotifs

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGmotifsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGmotifsUIFigure)
        end
    end
end